classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Inicio                     matlab.ui.container.Panel
        Image_2                    matlab.ui.control.Image
        TituloInicio               matlab.ui.control.Label
        EntrenamientoLabel         matlab.ui.control.Label
        Image2_2                   matlab.ui.control.Image
        DeteccindevehculosLabel    matlab.ui.control.Label
        BotonEntreno               matlab.ui.control.Button
        BotonRadar                 matlab.ui.control.Button
        VolverButton               matlab.ui.control.Button
        Principal                  matlab.ui.container.Panel
        Image                      matlab.ui.control.Image
        CiudadesinteligentesLabel  matlab.ui.control.Label
        GoogleNetLabel             matlab.ui.control.Label
        Image2                     matlab.ui.control.Image
        AlexNetLabel               matlab.ui.control.Label
        BotonGoogleNet             matlab.ui.control.Button
        BotonAlexNet               matlab.ui.control.Button
        Entrenamiento              matlab.ui.container.Panel
        Image_3                    matlab.ui.control.Image
        TituloEntrenamiento        matlab.ui.control.Label
        SubidadeimgenesLabel       matlab.ui.control.Label
        Image2_3                   matlab.ui.control.Image
        VisualizarimgenesLabel     matlab.ui.control.Label
        BotonSubida                matlab.ui.control.Button
        BotonVisualizar            matlab.ui.control.Button
        BotonVolverEntrenamiento   matlab.ui.control.Button
        Image2_4                   matlab.ui.control.Image
        EntrenarLabel              matlab.ui.control.Label
        BotonEntrenar              matlab.ui.control.Button
        Subida                     matlab.ui.container.Panel
        TituloSubida               matlab.ui.control.Label
        BotonElegir                matlab.ui.control.Button
        BotonSubir                 matlab.ui.control.Button
        BotonVolverSubida          matlab.ui.control.Button
        TipoDropDownLabel          matlab.ui.control.Label
        TipoDropDown               matlab.ui.control.DropDown
        DireccinDropDownLabel      matlab.ui.control.Label
        DireccinDropDown           matlab.ui.control.DropDown
        ImagenPrevisualizada       matlab.ui.control.Image
        Deteccion                  matlab.ui.container.Panel
        TituloDeteccion            matlab.ui.control.Label
        BotonSeleccionVideo        matlab.ui.control.Button
        BotonVolverDeteccion       matlab.ui.control.Button
        ImagenPrevisualizada_2     matlab.ui.control.Image
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        eleccion; % Description
        rutaEntrada;
    end
    
    methods (Access = private)
        
        function ocultar(app, numPanel)
            if numPanel == 1 % Principal
                app.Inicio.Visible = false;
                app.Principal.Visible = true;
            elseif numPanel == 2  % Inicio
                app.Inicio.Visible = true;
                app.Principal.Visible = false;
                app.Entrenamiento.Visible = false;
                if strcmp(app.eleccion, 'google')
                    app.TituloInicio.Text = 'GoogleNet';
                else
                    app.TituloInicio.Text = 'AlexNet';
                end
            elseif numPanel == 3 % Entrenamiento
                app.Entrenamiento.Visible = true;
                app.Inicio.Visible = false;
                app.Subida.Visible = false;
                if strcmp(app.eleccion, 'google')
                    app.TituloEntrenamiento.Text = 'Entrenamiento (GoogleNet)';
                else
                    app.TituloEntrenamiento.Text = 'Entrenamiento (AlexNet)';
                end
            elseif numPanel == 4 % Subida
                app.Subida.Visible = true;
                app.Entrenamiento.Visible = false;
            end
            
        end
        
        function reiniciarSubida(app)
            delete(app.ImagenPrevisualizada);
            app.ImagenPrevisualizada = uiimage(app.Subida);
            app.ImagenPrevisualizada.Position = [86 180 146 144];
            app.BotonSubir.Enable = false;
            app.TipoDropDown.Value = 'Asfalto';
            app.DireccinDropDown.Value = 'Delantera';
            app.DireccinDropDown.Enable = false;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BotonGoogleNet
        function BotonGoogleNetButtonPushed(app, event)
            app.eleccion = 'google';
            app.ocultar(2);
        end

        % Button pushed function: BotonAlexNet
        function BotonAlexNetButtonPushed(app, event)
            app.eleccion = 'alex';
            app.ocultar(2);
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            app.ocultar(1);
        end

        % Button pushed function: BotonVolverEntrenamiento
        function BotonVolverEntrenamientoButtonPushed(app, event)
            app.ocultar(2);
        end

        % Button pushed function: BotonVisualizar
        function BotonVisualizarButtonPushed(app, event)
            if strcmp(app.eleccion, 'google')
                    winopen('VehiculosGoogleNet\');
                else
                    winopen('VehiculosAlexNet\');
            end
            
        end

        % Button pushed function: BotonSubida
        function BotonSubidaButtonPushed(app, event)
            app.ocultar(4);
        end

        % Button pushed function: BotonEntrenar
        function BotonEntrenarButtonPushed(app, event)
            if strcmp(app.eleccion, 'google')
                    run('TrainingGoogle.m');
                else
                    run('TrainingAlex.m');
            end
            
        end

        % Button pushed function: BotonRadar
        function BotonRadarButtonPushed(app, event)
            if strcmp(app.eleccion, 'google')
                    run('ClasificacionGoogleNet.m');
                else
                    run('ClasificacionAlexNet.m');
            end
            
        end

        % Button pushed function: BotonEntreno
        function BotonEntrenoButtonPushed(app, event)
            app.ocultar(3);
        end

        % Button pushed function: BotonVolverSubida
        function BotonVolverSubidaButtonPushed(app, event)
            app.ocultar(3);
            app.reiniciarSubida();
        end

        % Button pushed function: BotonSubir
        function BotonSubirButtonPushed(app, event)
            I = imread(app.rutaEntrada);
            value = app.TipoDropDown.Value;
            if strcmp(value, 'Coche')
                valorDireccion = app.DireccinDropDown.Value;
                value = strcat(value, valorDireccion);
            end
            if strcmp(app.eleccion, 'google')
                ruta = strcat('VehiculosGoogleNet\', value);
                I = imresize(I, [224 224]);
            else
                ruta = strcat('VehiculosAlexNet\', value);
                I = imresize(I, [227 227]); 
            end
            i = 1;
            while isfile(strcat(ruta, '\',value(1:1), num2str(i,'%02.f'), 'r.jpg'))
                i = i + 1;
            end
            imwrite(I,strcat(ruta, '\',value(1:1), num2str(i,'%02.f'), 'r.jpg'))
            if isfile(strcat(ruta, '\',value(1:1), num2str(i,'%02.f'), 'r.jpg'))
                msgbox('Subida completada');
                app.reiniciarSubida();
            else
                msgbox('Error en la subida', '', 'error');
            end
            
        end

        % Button pushed function: BotonElegir
        function BotonElegirButtonPushed(app, event)
            [file,folder] = uigetfile({'*.jpg'});
           app.rutaEntrada = fullfile(folder, file);
            aux = file(end-3:end);
            if strlength(file) > 3 &&  strcmp(aux, '.jpg')
                app.ImagenPrevisualizada.ImageSource = app.rutaEntrada;
                app.BotonSubir.Enable = true;
            else
                msgbox('Formato de archivo no válido', '', "error");
            end
            
        end

        % Value changed function: TipoDropDown
        function TipoDropDownValueChanged(app, event)
            value = app.TipoDropDown.Value;
            if strcmp(value, 'Coche')
                app.DireccinDropDown.Enable = true;
            else
                app.DireccinDropDown.Enable = false;
            end
        end

        % Button pushed function: BotonSeleccionVideo
        function BotonSeleccionVideoButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Inicio = uipanel(app.UIFigure);
            app.Inicio.Visible = 'off';
            app.Image_2 = uiimage(app.Inicio);
            app.Image_2.ImageSource = 'thought.svg';
            app.TituloInicio = uilabel(app.Inicio);
            app.TituloInicio.Text = '';
            app.EntrenamientoLabel = uilabel(app.Inicio);
            app.EntrenamientoLabel.Text = 'Entrenamiento';
            app.Image2_2 = uiimage(app.Inicio);
            app.Image2_2.ImageSource = 'radar.svg';
            app.DeteccindevehculosLabel = uilabel(app.Inicio);
            app.DeteccindevehculosLabel.Text = 'Detección de vehículos';
            app.BotonEntreno = uibutton(app.Inicio, 'push');
            app.BotonEntreno.ButtonPushedFcn = createCallbackFcn(app, @BotonEntrenoButtonPushed, true);
            app.BotonEntreno.Text = 'Seleccionar';
            app.BotonRadar = uibutton(app.Inicio, 'push');
            app.BotonRadar.ButtonPushedFcn = createCallbackFcn(app, @BotonRadarButtonPushed, true);
            app.BotonRadar.Text = 'Seleccionar';
            app.VolverButton = uibutton(app.Inicio, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = 'Volver';
            app.Principal = uipanel(app.UIFigure);
            app.Image = uiimage(app.Principal);
            app.Image.ImageSource = 'google.svg';
            app.CiudadesinteligentesLabel = uilabel(app.Principal);
            app.CiudadesinteligentesLabel.Text = 'Ciudades inteligentes';
            app.GoogleNetLabel = uilabel(app.Principal);
            app.GoogleNetLabel.Text = 'GoogleNet';
            app.Image2 = uiimage(app.Principal);
            app.Image2.ImageSource = 'deep-learning.svg';
            app.AlexNetLabel = uilabel(app.Principal);
            app.AlexNetLabel.Text = 'AlexNet';
            app.BotonGoogleNet = uibutton(app.Principal, 'push');
            app.BotonGoogleNet.ButtonPushedFcn = createCallbackFcn(app, @BotonGoogleNetButtonPushed, true);
            app.BotonGoogleNet.Text = 'Seleccionar';
            app.BotonAlexNet = uibutton(app.Principal, 'push');
            app.BotonAlexNet.ButtonPushedFcn = createCallbackFcn(app, @BotonAlexNetButtonPushed, true);
            app.BotonAlexNet.Text = 'Seleccionar';
            app.Entrenamiento = uipanel(app.UIFigure);
            app.Entrenamiento.Visible = 'off';
            app.Image_3 = uiimage(app.Entrenamiento);
            app.Image_3.ImageSource = 'upload.svg';
            app.TituloEntrenamiento = uilabel(app.Entrenamiento);
            app.TituloEntrenamiento.Text = '';
            app.SubidadeimgenesLabel = uilabel(app.Entrenamiento);
            app.SubidadeimgenesLabel.Text = 'Subida de imágenes';
            app.Image2_3 = uiimage(app.Entrenamiento);
            app.Image2_3.ImageSource = 'folder.svg';
            app.VisualizarimgenesLabel = uilabel(app.Entrenamiento);
            app.VisualizarimgenesLabel.Text = 'Visualizar imágenes';
            app.BotonSubida = uibutton(app.Entrenamiento, 'push');
            app.BotonSubida.ButtonPushedFcn = createCallbackFcn(app, @BotonSubidaButtonPushed, true);
            app.BotonSubida.Text = 'Seleccionar';
            app.BotonVisualizar = uibutton(app.Entrenamiento, 'push');
            app.BotonVisualizar.ButtonPushedFcn = createCallbackFcn(app, @BotonVisualizarButtonPushed, true);
            app.BotonVisualizar.Text = 'Seleccionar';
            app.BotonVolverEntrenamiento = uibutton(app.Entrenamiento, 'push');
            app.BotonVolverEntrenamiento.ButtonPushedFcn = createCallbackFcn(app, @BotonVolverEntrenamientoButtonPushed, true);
            app.BotonVolverEntrenamiento.Text = 'Volver';
            app.Image2_4 = uiimage(app.Entrenamiento);
            app.Image2_4.ImageSource = 'presentation.svg';
            app.EntrenarLabel = uilabel(app.Entrenamiento);
            app.EntrenarLabel.Text = 'Entrenar';
            app.BotonEntrenar = uibutton(app.Entrenamiento, 'push');
            app.BotonEntrenar.ButtonPushedFcn = createCallbackFcn(app, @BotonEntrenarButtonPushed, true);
            app.BotonEntrenar.Text = 'Seleccionar';
            app.Subida = uipanel(app.UIFigure);
            app.Subida.Visible = 'off';
            app.TituloSubida = uilabel(app.Subida);
            app.TituloSubida.Text = 'Subida de imágenes';
            app.BotonElegir = uibutton(app.Subida, 'push');
            app.BotonElegir.ButtonPushedFcn = createCallbackFcn(app, @BotonElegirButtonPushed, true);
            app.BotonElegir.Text = 'Elegir imagen';
            app.BotonSubir = uibutton(app.Subida, 'push');
            app.BotonSubir.ButtonPushedFcn = createCallbackFcn(app, @BotonSubirButtonPushed, true);
            app.BotonSubir.Enable = 'off';
            app.BotonSubir.Text = 'Subir imagen';
            app.BotonVolverSubida = uibutton(app.Subida, 'push');
            app.BotonVolverSubida.ButtonPushedFcn = createCallbackFcn(app, @BotonVolverSubidaButtonPushed, true);
            app.BotonVolverSubida.Text = 'Volver';
            app.TipoDropDownLabel = uilabel(app.Subida);
            app.TipoDropDownLabel.Text = 'Tipo';
            app.TipoDropDown = uidropdown(app.Subida);
            app.TipoDropDown.Items = {'Asfalto', 'Bus', 'CamionFurgo', 'Coche', 'Lineas', 'Moto', 'Muro'};
            app.TipoDropDown.ValueChangedFcn = createCallbackFcn(app, @TipoDropDownValueChanged, true);
            app.TipoDropDown.Value = 'Asfalto';
            app.DireccinDropDownLabel = uilabel(app.Subida);
            app.DireccinDropDownLabel.Text = 'Dirección';
            app.DireccinDropDown = uidropdown(app.Subida);
            app.DireccinDropDown.Items = {'Delantera', 'Trasera'};
            app.DireccinDropDown.Enable = 'off';
            app.DireccinDropDown.Value = 'Delantera';
            app.ImagenPrevisualizada = uiimage(app.Subida);
            app.Deteccion = uipanel(app.UIFigure);
            app.TituloDeteccion = uilabel(app.Deteccion);
            app.TituloDeteccion.Text = 'Detección de vehículos';
            app.BotonSeleccionVideo = uibutton(app.Deteccion, 'push');
            app.BotonSeleccionVideo.ButtonPushedFcn = createCallbackFcn(app, @BotonSeleccionVideoButtonPushed, true);
            app.BotonSeleccionVideo.Text = 'Seleccionar video';
            app.BotonVolverDeteccion = uibutton(app.Deteccion, 'push');
            app.BotonVolverDeteccion.Text = 'Volver';
            app.ImagenPrevisualizada_2 = uiimage(app.Deteccion);
            app.UIAxes = uiaxes(app.Deteccion);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end